classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ResetButton                   matlab.ui.control.Button
        StopButton                    matlab.ui.control.Button
        FastSimulationButton          matlab.ui.control.Button
        HTML                          matlab.ui.control.HTML
        RunButton                     matlab.ui.control.Button
        ExertedForcePanel             matlab.ui.container.Panel
        MagnitudeSlider               matlab.ui.control.Slider
        MagnitudeSliderLabel          matlab.ui.control.Label
        PhiAngleDegreeSliderLabel     matlab.ui.control.Label
        phiAngle                      matlab.ui.control.Slider
        ThetaAngleDegreeSliderLabel   matlab.ui.control.Label
        thetaAngle                    matlab.ui.control.Slider
        ConfigurationPanel            matlab.ui.container.Panel
        dataPoints                    matlab.ui.control.NumericEditField
        DatapointsLabel               matlab.ui.control.Label
        tetherLength                  matlab.ui.control.NumericEditField
        TetherLengthnmLabel           matlab.ui.control.Label
        Rb                            matlab.ui.control.NumericEditField
        BeadRadiusnmLabel             matlab.ui.control.Label
        eta                           matlab.ui.control.NumericEditField
        LiquidviscousityPasLabel      matlab.ui.control.Label
        temperature                   matlab.ui.control.NumericEditField
        TemperatureCelciusLabel       matlab.ui.control.Label
        PersistenceLengthnmEditFieldLabel  matlab.ui.control.Label
        PersistenceLengthnmEditField  matlab.ui.control.NumericEditField
        DNAForce                      matlab.ui.control.UIAxes
        ZPosition                     matlab.ui.control.UIAxes
        XYPosition                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        isRunning_ = false; % Description
        %magnitude of the force for fast simulation
        laserForce_;
        %%magnitude of the force for real-time simulation
        laserForce_realtime;
        theta_;
        phi_;
        dataPoints_;
        exertedForceDuration_ = [0.33 0.66];
        Lo_;
        Lp_;
        Rb_;
        eta_;
        temperature_;
        sita0_;
        sita_;
        currentTime_;
        frameDataPoints_;
        isDone_;
        %Need X, Y, Z sim here to continue the simulation
        Xsim_;
        Ysim_;
        Zsim_;
        forceX_;
        forceY_;
        forceZ_;
        fullExtension_;
        DNAForce_;
        AnglePhiSim_;
        AngleThetaSim_;
        AngleSimThetaDegree_;
        AngleSimPhiDegree_;
        deltat_;
        Timer_;          % Timer object
        
        timeline_;
        fullTimeline_;
        
        plotZPositionObj_;
        plotXYPositionObj_;
        plotDNAForceObj_;
        plotXYZPosition_3D_;
        forceApplied_;

            
    end
    
    methods (Access = private)
        
        function results = simulateOneStep(app, ~, ~)
        app.RunButton.Enable = 'off';
        app.isRunning_ = true;
        app.isDone_ = false;
        i = app.currentTime_;
        
        kbT = (1.38*10^-23) * (app.temperature_ + 273);
        D = kbT/app.sita_;
        Lp= app.Lp_;
        Lo = app.Lo_;


        fLaser = app.laserForce_realtime;
        fTemp = fLaser/10e9;
        
        alpha = app.theta_;
        beta = app.phi_;
            
        extensionX = app.Xsim_(i-1);
        extensionY = app.Ysim_(i-1);
        extensionZ = app.Zsim_(i-1);
        
        anglePhi = app.AnglePhiSim_(i-1);
        angleTheta = app.AngleThetaSim_(i-1);
        
    
        extension = sqrt(extensionX^2 + extensionY^2 + extensionZ^2);
        
        DNAForceX = Marko_Sigga(kbT, Lp, Lo, extension, extensionX,'x', angleTheta, anglePhi);
        DNAForceY = Marko_Sigga(kbT, Lp, Lo, extension, extensionY,'y', angleTheta, anglePhi);
        DNAForceZ = Marko_Sigga(kbT, Lp, Lo, extension, extensionZ,'z', angleTheta, anglePhi);
        
        %Net force for each dimension
        app.forceX_(i-1) = DNAForceX + fTemp*sin(alpha)*cos(beta); % giga Newton
        app.forceY_(i-1) = DNAForceY + fTemp*sin(alpha)*sin(beta); % giga  Newton
        app.forceZ_(i-1) = DNAForceZ + fTemp*cos(alpha); % giga  Newton
        
        forceX = app.forceX_(i-1);
        forceY = app.forceY_(i-1);
        forceZ = app.forceZ_(i-1);
        
        %take dFx, dFy, dFz => cal next time step
        
        if i <=2
            constantCoeff = kbT/(Lp*Lo);
            change = 0.5/((1 - extension/Lo)^3) + 1;
            gradient = abs(constantCoeff*change);
            app.deltat_ = (2*0.01 * app.sita0_) / gradient;
        else
        
        app.deltat_ = getNextTimeStep_(app);
        end
        deltat = app.deltat_;


        ldiff   = sqrt(2*D*deltat); 
        brownianTerms = normrnd(0,ldiff,3,1);
        brownianTermsX = brownianTerms(1);
        brownianTermsY = brownianTerms(2);
        brownianTermsZ = brownianTerms(3);
        
        sitaX = getDragCoefXY(app.Rb_, extensionZ, app.sita0_);
        sitaY = sitaX;
        sitaZ = getDragCoefZ(app.Rb_, extensionZ, app.sita0_);
            
        deltaX= (forceX*deltat)/sitaX + brownianTermsX;
        app.Xsim_(i)=app.Xsim_(i-1)+deltaX;%new element value  
       
        
        deltaY= (forceY*deltat)/sitaY + brownianTermsY;
        app.Ysim_(i)=app.Ysim_(i-1)+deltaY;%new element value 
        
        deltaZ= (forceZ*deltat)/sitaZ + brownianTermsZ;
       
        while (app.Zsim_(i-1) + deltaZ < app.Rb_)
            deltaZ = (forceZ*deltat)/sitaZ +  normrnd(0,ldiff,1,1);
        end
        app.Zsim_(i)=app.Zsim_(i-1)+deltaZ;
        
        app.fullExtension_(i) = sqrt(app.Xsim_(i)^2 + app.Ysim_(i)^2 + app.Zsim_(i)^2);


         app.AnglePhiSim_(i) = atan(app.Ysim_(i)/app.Xsim_(i));
         app.AngleSimPhiDegree_(i) = 180*(app.AnglePhiSim_(i)/pi);
         
         app.AngleThetaSim_(i) = atan(sqrt(app.Xsim_(i)^2 + app.Ysim_(i)^2) / app.Zsim_(i));
         app.AngleSimThetaDegree_(i) = 180*(app.AngleThetaSim_(i)/pi);
         
         app.DNAForce_(i) = 10e9 * sqrt(DNAForceX^2 + DNAForceY^2 + DNAForceZ^2);
         app.forceApplied_(i) = fLaser;
         app.fullTimeline_(i) = app.fullTimeline_(i-1) + deltat; 
 
        sita = 0;
        if (sitaX < sitaZ)
            sita = sitaX;
        else sita = sitaZ;
        end
      
       
       %Vertical values of Z Position _ PLot Z coordinate
       xData = app.plotZPositionObj_.XData;
       xData = circshift(xData, 1);
      %shift the timeline 
       xData(1) = xData(2) + deltat*1000;
       app.plotZPositionObj_.XData = xData;
       yData = app.plotZPositionObj_.YData;
       yData = circshift(yData,1);
       yData(1) = app.Zsim_(i);
       app.plotZPositionObj_.YData = yData;
       
       %Vertical values of XY_Pos
   
    app.plotXYPositionObj_(1).XData = xData;
    app.plotXYPositionObj_(2).XData = xData;
       
    
    yData_X = app.plotXYPositionObj_(1).YData;
    yData_X = circshift(yData_X, 1);
    yData_X(1) = app.Xsim_(i);
    app.plotXYPositionObj_(1).YData = yData_X;
    
    yData_Y = app.plotXYPositionObj_(2).YData;
    yData_Y = circshift(yData_Y, 1);
    yData_Y(1) = app.Ysim_(i);
    app.plotXYPositionObj_(2).YData = yData_Y;
    
      %Vertical values of DNA stretching force
      app.plotDNAForceObj_(1).XData = xData;
      app.plotDNAForceObj_(2).XData = xData;
    
    
    yData_DNAForce = app.plotDNAForceObj_(1).YData;
    yData_DNAForce = circshift(yData_DNAForce, 1);
    yData_DNAForce(1) = app.DNAForce_(i);
    app.plotDNAForceObj_(1).YData = yData_DNAForce;
    
    yData_appliedForce = app.plotDNAForceObj_(2).YData;
    yData_appliedForce = circshift(yData_appliedForce, 1);
    yData_appliedForce(1) = app.forceApplied_(i);
    app.plotDNAForceObj_(2).YData = yData_appliedForce;
    i = i + 1;
    app.currentTime_ = i;
        
    if (i >= app.dataPoints_)
       stop(app.Timer_);
    end
    
    %dummy returned value
     results = 1;
            
      end
        function results = plotCurrentData_(app)
            i = app.currentTime_;
            subplot(1,3,1);
            plot(app.fullTimeline_(1:i-1), app.Zsim_(1:i-1), 'b',app.fullTimeline_(1:i-1),app.fullExtension_(1:i-1));
            title ('Z Position and Full Extension');
            legend("Z Position", "Full Extension");
            xlabel ('Time (sec)');
            ylabel ('Bead Position (nm)');
            
            subplot(1,3,2);
            plot(app.fullTimeline_(1:i-1), app.Xsim_(1:i-1), 'b',app.fullTimeline_(1:i-1), app.Ysim_(1:i-1));
            title ('XY Position');
            legend("X", "Y");
            xlabel ('Time (ms)');
            ylabel ('Bead Position (nm)');
            
            subplot(1,3,3);
            plot(app.fullTimeline_(1:i-1), app.DNAForce_(1:i-1)*10^12, 'b',app.fullTimeline_(1:i-1), app.forceApplied_(1:i-1)*10^12);
            title ('DNA Force _ Exerted Force');
            legend("DNA Stretching Force", "Exerted Force");
            xlabel ('Time (ms)');
            ylabel ('Force (pN)');
            results = 1;

         
        end
        
        function results =getNextTimeStep_(app)
          i = app.currentTime_;
          dF_x = (app.forceX_(i-1) - app.forceX_(i-2))/(app.Xsim_(i-1) - app.Xsim_(i-2));
          dF_y = (app.forceY_(i-1) - app.forceY_(i-2))/(app.Ysim_(i-1) - app.Ysim_(i-2));
          dF_z = (app.forceZ_(i-1) - app.forceZ_(i-2))/(app.Zsim_(i-1) - app.Zsim_(i-2));
          
          gradient = sqrt(dF_x^2 + dF_y^2 + dF_z^2);
           
          results = (2*0.01 * app.sita0_) / gradient;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        app.laserForce_realtime = app.MagnitudeSlider.Value * 10^-12;
        app.laserForce_ = app.laserForce_realtime;
        app.theta_ = pi*(app.thetaAngle.Value/180);
        app.phi_ = pi * (app.phiAngle.Value/180);
        app.dataPoints_ = app.dataPoints.Value;
        app.Lo_ = app.tetherLength.Value;
        app.Lp_ = app.PersistenceLengthnmEditField.Value;
        app.Rb_ = app.Rb.Value;
        app.eta_ = app.eta.Value * 10^-30;
        app.temperature_ = app.temperature.Value;
        app.sita0_ = 6*pi*app.eta_*app.Rb_;
        app.sita_ = app.sita0_;
        app.currentTime_ = 2;
        app.frameDataPoints_ = 200;
        app.isDone_ = true;
        n = app.dataPoints_;
        app.Xsim_=zeros(1,n);
        app.Ysim_=zeros(1,n);
        app.Zsim_=zeros(1,n);
        app.Zsim_ =  app.Zsim_ + app.Rb_;
        app.fullExtension_ = zeros(1,n);
        app.fullTimeline_ = zeros(1,n);
        app.AnglePhiSim_ = zeros(1,n);
        app.AngleThetaSim_ = zeros(1,n);
        app.AngleThetaSim_ = app.AngleThetaSim_ + pi/2;
        app.AngleSimThetaDegree_ = zeros(1,n);
        app.AngleSimPhiDegree_ = zeros(1,n);
        app.DNAForce_ = zeros(1,n);
        app.forceX_ = zeros(1,n);
        app.forceY_ = zeros(1,n);
        app.forceZ_ = zeros(1,n);
        app.timeline_ = zeros(1, app.frameDataPoints_ + 1);
       
        app.forceApplied_ = zeros(1, n);
        
        % Create timer object
         app.Timer_ = timer(...
                'ExecutionMode', 'fixedSpacing', ...    % Run timer repeatedly
                'Period', 0.02, ...                     % Period is 0.02 second
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.simulateOneStep);      % Specify callback function
         %ZPosition Graph Obj
         app.plotZPositionObj_ = plot(app.ZPosition, app.timeline_, zeros(1, app.frameDataPoints_+1));
         app.ZPosition.YLim = [0 app.Lo_];
         app.ZPosition.YTick = [0 app.Lo_*0.33 app.Lo_*0.66 app.Lo_ app.Lo_*1.33];
         app.ZPosition.YTickLabel = [0 app.Lo_*0.33 app.Lo_*0.66 app.Lo_ app.Lo_*1.33];
         

         app.plotXYPositionObj_ = plot(app.XYPosition,app.timeline_, zeros(1, app.frameDataPoints_+1),app.timeline_, zeros(1, app.frameDataPoints_+1));
         legend(app.XYPosition,'X' , 'Y');
         app.XYPosition.YLim = [-app.Lo_ app.Lo_];
         app.XYPosition.YTick = [-app.Lo_ -app.Lo_*0.66 -app.Lo_*0.33 0 app.Lo_*0.33 app.Lo_*0.66 app.Lo_ app.Lo_*1.33];
         app.XYPosition.YTickLabel = [-app.Lo_ -app.Lo_*0.66 -app.Lo_*0.33 0 app.Lo_*0.33 app.Lo_*0.66 app.Lo_ app.Lo_*1.33];
         
         
         %ForcePlot Obj
         app.plotDNAForceObj_ = plot(app.DNAForce, app.timeline_, zeros(1, app.frameDataPoints_+1), app.timeline_, zeros(1, app.frameDataPoints_+1));
         legend(app.DNAForce, "DNA Stretching Force", "Exerted Force");
        
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
            % If timer is not running, start it
            if strcmp(app.Timer_.Running, 'off')
               start(app.Timer_);
            end
%         app.isRunning_ = true;
%         simulate(app);
%                       
        end

        % Data changed function: HTML
        function HTMLDataChanged(app, event)
            data = app.HTML.Data;
            
        end

        % Callback function
        function ForceSliderValueChanging(app, event)
            
        end

        % Value changed function: MagnitudeSlider
        function MagnitudeSliderValueChanged(app, event)
            value = app.MagnitudeSlider.Value;
            app.laserForce_realtime = value*10^-12;
            app.laserForce_ = app.laserForce_realtime;
            
        end

        % Button pushed function: FastSimulationButton
        function FastSimulationButtonPushed(app, event)
            close all;
            app.FastSimulationButton.Enable = 'off';
            pause(0.5);
            TetheredParticleAnalysis_WithDependentTimestep_3D(app.laserForce_, app.theta_ , app.phi_ , app.exertedForceDuration_, 0.01, app.dataPoints_, app.Lo_, app.Rb_, app.eta_, app.temperature_);
            app.FastSimulationButton.Enable = 'on';
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.isRunning_ = false;
            close all;
            app.isDone_ = true;
            stop(app.Timer_);
            plotCurrentData_(app);
            pause(0.75);
            app.RunButton.Enable = 'on';
        end

        % Callback function
        function MagnitudeFieldValueChanged(app, event)
            value = app.MagnitudeField.Value;
            app.laserForce_ = value*10^-12;
        end

        % Callback function
        function phiAngleValueChanged(app, event)
            value = app.phiAngle.Value;
            app.phi_ = value/180 * pi;
        end

        % Callback function
        function thetaAngleValueChanged(app, event)
            value = app.thetaAngle.Value;
            app.theta_ = value/180 * pi;
        end

        % Value changed function: dataPoints
        function dataPointsValueChanged(app, event)
            value = app.dataPoints.Value;
            app.dataPoints_ = value;
            n = value;
            app.Xsim_=zeros(1,n);
            app.Ysim_ =zeros(1,n);
            app.Zsim_=zeros(1,n);
            app.Zsim_ =  app.Zsim_ + app.Rb_;
            app.AnglePhiSim_ = zeros(1,n);
            app.AngleThetaSim_ = zeros(1,n);
            app.AngleThetaSim_ = app.AngleThetaSim_ + pi/2;
            app.AngleSimThetaDegree_ = zeros(1,n);
            app.AngleSimPhiDegree_ = zeros(1,n);
            app.DNAForce_ = zeros(1,n);
            app.fullTimeline_ = zeros(1, n);
            app.fullExtension_ = zeros(1, n);
        end

        % Value changed function: tetherLength
        function tetherLengthValueChanged(app, event)
            value = app.tetherLength.Value;
            app.Lo_ = value;
            
            %scaling the tether length when it is stretched
            app.ZPosition.YLim = [0 app.Lo_*1.33];
            app.ZPosition.YTick = [0 app.Lo_*0.33 app.Lo_*0.66 app.Lo_ app.Lo_*1.33];
            app.ZPosition.YTickLabel = [0 app.Lo_*0.33 app.Lo_*0.66 app.Lo_ app.Lo_*1.33];
            
            app.XYPosition.YLim = [-app.Lo_*1.33 app.Lo_*1.33];
            app.XYPosition.YTick = [-app.Lo_ -app.Lo_*0.66 -app.Lo_*0.33 0 app.Lo_*0.33 app.Lo_*0.66 app.Lo_ app.Lo_*1.33];
            app.XYPosition.YTickLabel = [-app.Lo_ -app.Lo_*0.66 -app.Lo_*0.33 0 app.Lo_*0.33 app.Lo_*0.66 app.Lo_ app.Lo_*1.33];
        end

        % Value changed function: Rb
        function RbValueChanged(app, event)
            value = app.Rb.Value;
            app.Rb_ = value;
        end

        % Value changed function: eta
        function etaValueChanged(app, event)
            value = app.eta.Value;
            app.eta_ = value*10^-27;
            app.sita0_    = 6*pi*app.eta_*app.Rb_; 
        end

        % Value changed function: temperature
        function temperatureValueChanged(app, event)
            value = app.temperature.Value;
            app.temperature_ = value;
           
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
           cla(app.ZPosition);
           cla(app.XYPosition);
           cla(app.DNAForce);
           stop(app.Timer_);
           delete(app.Timer_);
           %set up everything like in the beginning
           startupFcn(app);
        end

        % Value changed function: thetaAngle
        function thetaAngleValueChanged2(app, event)
            value = app.thetaAngle.Value;
            app.theta_ = (value/180)*pi;
        end

        % Value changed function: phiAngle
        function phiAngleValueChanged2(app, event)
            value = app.phiAngle.Value;
            app.phi_ = (value/180) * pi;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.Timer_);
            delete(app.Timer_);
            delete(app);
            
        end

        % Value changed function: PersistenceLengthnmEditField
        function PersistenceLengthnmEditFieldValueChanged(app, event)
            value = app.PersistenceLengthnmEditField.Value;
            app.Lp_ = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.FastSimulationButton = uibutton(app.UIFigure, 'push');
            app.FastSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @FastSimulationButtonPushed, true);
            app.FastSimulationButton.Text = 'Fast Simulation';
            app.HTML = uihtml(app.UIFigure);
            app.HTML.HTMLSource = 'index.html';
            app.HTML.DataChangedFcn = createCallbackFcn(app, @HTMLDataChanged, true);
            app.HTML.Tooltip = {''; ''};
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ExertedForcePanel = uipanel(app.UIFigure);
            app.ExertedForcePanel.Title = 'Exerted Force';
            app.MagnitudeSlider = uislider(app.ExertedForcePanel);
            app.MagnitudeSlider.Limits = [0 35];
            app.MagnitudeSlider.ValueChangedFcn = createCallbackFcn(app, @MagnitudeSliderValueChanged, true);
            app.MagnitudeSliderLabel = uilabel(app.ExertedForcePanel);
            app.MagnitudeSliderLabel.Text = 'Magnitude';
            app.PhiAngleDegreeSliderLabel = uilabel(app.ExertedForcePanel);
            app.PhiAngleDegreeSliderLabel.Text = 'Phi Angle (Degree)';
            app.phiAngle = uislider(app.ExertedForcePanel);
            app.phiAngle.Limits = [0 90];
            app.phiAngle.ValueChangedFcn = createCallbackFcn(app, @phiAngleValueChanged2, true);
            app.ThetaAngleDegreeSliderLabel = uilabel(app.ExertedForcePanel);
            app.ThetaAngleDegreeSliderLabel.Text = 'Theta Angle(Degree)';
            app.thetaAngle = uislider(app.ExertedForcePanel);
            app.thetaAngle.Limits = [0 90];
            app.thetaAngle.ValueChangedFcn = createCallbackFcn(app, @thetaAngleValueChanged2, true);
            app.ConfigurationPanel = uipanel(app.UIFigure);
            app.ConfigurationPanel.Title = 'Configuration';
            app.dataPoints = uieditfield(app.ConfigurationPanel, 'numeric');
            app.dataPoints.Limits = [100 Inf];
            app.dataPoints.ValueChangedFcn = createCallbackFcn(app, @dataPointsValueChanged, true);
            app.dataPoints.Value = 100;
            app.DatapointsLabel = uilabel(app.ConfigurationPanel);
            app.DatapointsLabel.Text = 'Data points';
            app.tetherLength = uieditfield(app.ConfigurationPanel, 'numeric');
            app.tetherLength.Limits = [100 Inf];
            app.tetherLength.ValueChangedFcn = createCallbackFcn(app, @tetherLengthValueChanged, true);
            app.tetherLength.Value = 100;
            app.TetherLengthnmLabel = uilabel(app.ConfigurationPanel);
            app.TetherLengthnmLabel.Text = 'Tether Length (nm)';
            app.Rb = uieditfield(app.ConfigurationPanel, 'numeric');
            app.Rb.Limits = [50 Inf];
            app.Rb.ValueChangedFcn = createCallbackFcn(app, @RbValueChanged, true);
            app.Rb.Value = 50;
            app.BeadRadiusnmLabel = uilabel(app.ConfigurationPanel);
            app.BeadRadiusnmLabel.Text = 'Bead Radius (nm)';
            app.eta = uieditfield(app.ConfigurationPanel, 'numeric');
            app.eta.Limits = [1e-05 5];
            app.eta.ValueChangedFcn = createCallbackFcn(app, @etaValueChanged, true);
            app.eta.Value = 0.0089;
            app.LiquidviscousityPasLabel = uilabel(app.ConfigurationPanel);
            app.LiquidviscousityPasLabel.Text = 'Liquid viscousity (Pa*s)';
            app.temperature = uieditfield(app.ConfigurationPanel, 'numeric');
            app.temperature.Limits = [-272 Inf];
            app.temperature.ValueChangedFcn = createCallbackFcn(app, @temperatureValueChanged, true);
            app.temperature.Value = 25;
            app.TemperatureCelciusLabel = uilabel(app.ConfigurationPanel);
            app.TemperatureCelciusLabel.Text = 'Temperature (Celcius) ';
            app.PersistenceLengthnmEditFieldLabel = uilabel(app.ConfigurationPanel);
            app.PersistenceLengthnmEditFieldLabel.Text = 'Persistence Length(nm)';
            app.PersistenceLengthnmEditField = uieditfield(app.ConfigurationPanel, 'numeric');
            app.PersistenceLengthnmEditField.Limits = [5 200];
            app.PersistenceLengthnmEditField.ValueChangedFcn = createCallbackFcn(app, @PersistenceLengthnmEditFieldValueChanged, true);
            app.PersistenceLengthnmEditField.Value = 5;
            app.DNAForce = uiaxes(app.UIFigure);
            title(app.DNAForce, 'Forces')
            xlabel(app.DNAForce, 'Time(ms)')
            ylabel(app.DNAForce, 'pN')
            zlabel(app.DNAForce, 'Z')
            app.DNAForce.YLim = [0 4e-11];
            app.DNAForce.YTickLabel = {'0'; '10'; '20'; '30'; '40'};
            app.ZPosition = uiaxes(app.UIFigure);
            title(app.ZPosition, 'Z Position')
            xlabel(app.ZPosition, 'Time (ms)')
            ylabel(app.ZPosition, 'nm')
            zlabel(app.ZPosition, {''; ''})
            app.ZPosition.YLim = [0 1500];
            app.ZPosition.YTickLabel = {'0'; '500'; '1000'; '1500'};
            app.XYPosition = uiaxes(app.UIFigure);
            title(app.XYPosition, 'XY Position')
            xlabel(app.XYPosition, 'Time (ms)')
            ylabel(app.XYPosition, 'nm')
            zlabel(app.XYPosition, 'Z')
            app.XYPosition.YLim = [-1000 1000];
            app.XYPosition.YTick = [-1000 0 1000];
            app.XYPosition.YTickLabel = {'-1000'; '0'; '1000'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end